classdef testAndes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        CargarArchivoButton  matlab.ui.control.Button
        EditField            matlab.ui.control.EditField
        ReflectanciaButton   matlab.ui.control.Button
        IndicesButton        matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        filename;
        pathname;
        reflectancia;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarArchivoButton
        function FileButtonPushed(app, event)
            [app.filename, app.pathname] = uigetfile({'*.sig'}, 'File Selector');
            if ~ischar(app.filename)
                return; %usuario cancelo la operacion
            end
            file = fullfile(app.pathname,app.filename);
            [fid, msg] = fopen(file,'r');
            disp('holi');
            if fid == -1
                error(msg);
            end
            figure(app.UIFigure)
            app.EditField.Value = app.filename;
            app.EditField.Editable = false;
        end

        % Button pushed function: ReflectanciaButton
        function ReflectanciaButtonPushed(app, event)
            
            [wavelength, data, new_wave, new_data] = leerArchivo(app.filename);
            [wave_new, data_new] = interpolar(wavelength, data, new_wave, new_data);
            app.reflectancia = calculoReflectancia(wave_new, data_new);
            
            indices = [350:1:2500];
            plot(app.UIAxes,indices,app.reflectancia);
            
        end

        % Button pushed function: IndicesButton
        function IndicesButtonPushed(app, event)
            app1(app.reflectancia);
            app.delete
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CargarArchivoButton = uibutton(app.LeftPanel, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.ReflectanciaButton = uibutton(app.LeftPanel, 'push');
            app.ReflectanciaButton.ButtonPushedFcn = createCallbackFcn(app, @ReflectanciaButtonPushed, true);
            app.ReflectanciaButton.Text = 'Reflectancia';
            app.IndicesButton = uibutton(app.LeftPanel, 'push');
            app.IndicesButton.ButtonPushedFcn = createCallbackFcn(app, @IndicesButtonPushed, true);
            app.IndicesButton.Text = 'Indices';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testAndes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end